classdef WireTwistViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InsulationRadinEditField       matlab.ui.control.NumericEditField
        InsulationRadinEditFieldLabel  matlab.ui.control.Label
        LayFactorEditField             matlab.ui.control.NumericEditField
        LayFactorEditFieldLabel        matlab.ui.control.Label
        NextLayerButton                matlab.ui.control.Button
        RemoveWireButton               matlab.ui.control.Button
        LengthinEditField              matlab.ui.control.NumericEditField
        LengthinEditFieldLabel         matlab.ui.control.Label
        AWGEditField                   matlab.ui.control.NumericEditField
        AWGEditFieldLabel              matlab.ui.control.Label
        LayerEditField                 matlab.ui.control.NumericEditField
        LayerEditFieldLabel            matlab.ui.control.Label
        AddWireButton                  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Layer Related Initialization
        LayFactor = 12;
        LayerMinAWG = [];
        LayerDistFromCent = [];
        LayerWiresPerLayer = [1,6,12,18,24,30,36,42]
        totalWiresPerLayer = [1,7,19,37,61,91,127,169]
        layer = 0

        % Geometry Objects
        circleCore         % Struct: [x, y, r] representing the current "floor"
        circleList = []    % Array of structs: Wires in the current layer
        circlePrev = []    % Struct: The neighbor wire for tangency math
    
        % State and Measurements
        coreBuilt = false  % Logical: Has the center wire been placed?
        distanceFromCenter = 0 % Double: Current radius of the "Virtual Core"
        biggestAWGRadius = 0   % Double: Largest radius found in the current layer
    end    
    
    methods (Access = private)
        
        function [circleCurrent, success] = addCircle(app,circleCore, circlePrev, circleCurrent, circleList, circleColor)
            % circleCore: struct with [x, y, r]
            % circlePrev: struct with [x, y, r] (or empty if first wire)
            % circleCurrent: struct with [r] (x and y will be calculated)

            success = false; % Assume failure until proven otherwise

            % 1. Handle the "First Wire" case
            if isempty(circlePrev)
                % This function will determine the starting position (e.g., at the top)
                % and call the plot command.
                circleCurrent = app.buildFirst(circleCore, circleCurrent);
                success = true; 
                return; % Exit function after placing first
            end

            % 2. Calculate the number of intersections (nOI)
            circleCurrent = app.calculateCircle(circleCore, circlePrev, circleCurrent);
            isValid = app.isFit(circleCurrent, circleList, circleCore);

            % 3. Placement Logic
            if isValid
                % This function will calculate the specific (x,y), update the 
                % circleCurrent struct, and plot it.
                app.plotCircle(circleCurrent, circleColor);
                success = true; % Mark as a valid placement
            else
                % If it doesn't fit, success stays false. 
                % We don't even need an 'else return' here; 
                % the function will just finish naturally.
            end
        end

        % 'Pass' logic 
        function pass(app)
            % Do nothing
        end

        % Calculates the circle given the circleCore and the circlePrev
        function circleCurrent = calculateCircle(app,circleCore, circlePrev, circleCurrent)
            % 1. Extract dimensions
            r1 = circleCore.r;
            r2 = circlePrev.r;
            r3 = circleCurrent.r;

            % Distances for the triangle sides
            R1 = r1 + r3; % Core to Current
            R2 = r2 + r3; % Prev to Current
    
            % 2. Calculate distance between existing centers (d)
            dx = circlePrev.x - circleCore.x;
            dy = circlePrev.y - circleCore.y;
            d = sqrt(dx^2 + dy^2);

            % 3. Triangle Geometry (The "Rearranged" Law of Cosines)
            % Projection distance along the line d
            a = (R1^2 - R2^2 + d^2) / (2 * d);
    
            % Perpendicular height
            % We use max(0, ...) to ensure that if floating-point errors make the
            % value slightly negative (e.g., -1e-15), sqrt doesn't return an imaginary number.
            h = sqrt(max(0, R1^2 - a^2)); 

            % 4. Find the Base Point (P_base) and Unit Vectors
            ux = dx / d; % Unit vector along the d-line
            uy = dy / d;
    
            x_base = circleCore.x + a * ux;
            y_base = circleCore.y + a * uy;

            % 5. Generate the two possible intersection points
            % Option 1: Positive rotation
            P1_x = x_base - h * uy;
            P1_y = y_base + h * ux;
    
            % Option 2: Negative rotation
            P2_x = x_base + h * uy;
            P2_y = y_base - h * ux;

            % 6. The "In-Depth" Choice: 2D Cross Product for CCW Orientation
            % Vector A: Core center to Previous wire center
            % Vector B: Core center to Potential New center (P1)
            Ax = circlePrev.x - circleCore.x;
            Ay = circlePrev.y - circleCore.y;
    
            B1x = P1_x - circleCore.x;
            B1y = P1_y - circleCore.y;

            % Calculate Z (The 2D Cross Product)
            Z = (Ax * B1y) - (Ay * B1x);

            % Selection: If Z > 0, P1 is to the LEFT of A (Counter-Clockwise)
            if Z > 0
                circleCurrent.x = P1_x;
                circleCurrent.y = P1_y;
            else
                circleCurrent.x = P2_x;
                circleCurrent.y = P2_y;
            end

        end

        % Builds the first circle around the core
        function circleCurrent = buildFirst(app,circleCore, circleCurrent)
            % 1. Calculate the North position
            % The x-coordinate stays the same as the Core
            circleCurrent.x = circleCore.x;
    
            % The y-coordinate is the Core center plus both radii
            circleCurrent.y = circleCore.y + circleCore.r + circleCurrent.r;

            % 2. Plotting (Your #plot comment)
            % Draw the circle and mark the center point
            viscircles(app.UIAxes,[circleCurrent.x, circleCurrent.y], circleCurrent.r, 'EdgeColor', 'g');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,circleCurrent.x, circleCurrent.y, 'k+');
          
        end
        % Tells us if the circleCurrent is good or not
        function isValid = isFit(app,circleCurrent, circleList, circleCore)
            % Default to true
            isValid = true;
    
            % Combine all obstacles
            allObstacles = [circleCore, circleList];
    
            % Tolerance for floating point math
            tol = 1e-9; 

            for i = 1:length(allObstacles)
                dx = circleCurrent.x - allObstacles(i).x;
                dy = circleCurrent.y - allObstacles(i).y;
                dist = sqrt(dx^2 + dy^2);
        
                sumR = circleCurrent.r + allObstacles(i).r;
        
                % Check for overlap
                % If distance is significantly less than the sum of radii, they overlap.
                if dist < (sumR - tol)
                    isValid = false;
                    return; % Exit early for efficiency
                end
            end
        end
        % Plots the Circle
        function plotCircle(app,circleCurrent, color)
            % Draw the perimeter
            viscircles(app.UIAxes,[circleCurrent.x, circleCurrent.y], circleCurrent.r, 'EdgeColor', color);
            hold(app.UIAxes, 'on');
            % Mark the center for harness routing alignment
            plot(app.UIAxes,circleCurrent.x, circleCurrent.y, 'k+'); 
        end
        function resetLPbA(app)
            % 1. Clear the obstacle list for the NEW layer
            % (We keep fullHarness elsewhere if you want to track the total bundle)
            app.circleList = [];    

            % 2. Reset the neighbor pointer to empty
            % This forces the NEXT wire added to use the 'buildFirst' (North) logic
            app.circlePrev = [];    

            % 3. Reset the "King Wire" tracker for the next necklace
            % This ensures the NEXT 'Next Layer' jump is based only on the new layer's wires
            app.biggestAWGRadius = 0; 

            fprintf('Layer state reset. Ready for next necklace.\n');
        end
        function refreshPlot(app)
            % Clear the plot area
            cla(app.UIAxes);
    
            if ~app.coreBuilt
                return
            end
    
            % 1. Redraw the Core
            viscircles(app.UIAxes, [0, 0], app.circleCore.r, 'EdgeColor', 'k', 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, 0, 0, 'k+'); 

            % 2. Redraw the "Shelf" (Dashed Circle) if we are in Layer 1+
            if app.layer > 0
                viscircles(app.UIAxes, [0, 0], app.circleCore.r, ...
                    'Color', [0.8 0.8 0.8], 'LineStyle', '--');
            end
    
            % 3. Loop through and redraw every wire in the current necklace
            for i = 1:length(app.circleList)
                w = app.circleList(i);
        
                % Skip the core if it's currently at the start of the list (Layer 0)
                if w.x == 0 && w.y == 0 && app.layer > 0, continue; end
        
                % Determine color (Green for the first/North wire, blue for others)
                if i == 1 && app.layer > 0
                    color = 'g';
                else
                    color = 'b';
                end
        
                viscircles(app.UIAxes, [w.x, w.y], w.r, 'EdgeColor', color);
                plot(app.UIAxes, w.x, w.y, 'k+');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddWireButton
        function AddWireButtonPushed(app, event)
                % Grab data                
                awg = app.AWGEditField.Value;
                wireLen = app.LengthinEditField.Value;
                insulationRad = app.InsulationRadinEditField.Value;
                % Safety check to prevent adding wires to the center after core is set
                if app.coreBuilt == true && app.layer == 0
                    return 
                end
                % Get Radius
                thisRadius = ((0.005 * (92^((36 - awg) / 39))) / 2) + insulationRad;
                if app.coreBuilt == false
                    % --- BUILD THE CORE (Layer 0) ---
                    app.circleCore.x = 0;
                    app.circleCore.y = 0;
                    app.circleCore.r = thisRadius;
        
                    % Set up the axes for the first time
                    hold(app.UIAxes, 'on');
                    axis(app.UIAxes, 'equal');
        
                    % Draw the Core (Black line)
                    viscircles(app.UIAxes, [0, 0], app.circleCore.r, 'EdgeColor', 'k', 'LineWidth', 2);
                    
                    % Update Table
                    newRow = {0, awg, insulationRad, wireLen, wireLen, 0, 1};
                    app.UITable.Data = [app.UITable.Data; newRow];

                    % Add the core to the obstacle list so the list is NOT empty
                    app.circleList = [app.circleList, app.circleCore];

                    % Update state for Layer 1
                    app.coreBuilt = true;
                    app.LayFactorEditField.Editable = 'off';
                    app.distanceFromCenter = thisRadius;
                else
                    % --- ADD WIRE TO CURRENT LAYER ---
                    trialWire = struct('r', thisRadius, 'x', 0, 'y', 0);
        
                    % Call the private method for the geometry math
                    [newWire, success] = app.addCircle(app.circleCore, app.circlePrev, trialWire, app.circleList, 'b');
        
                    if success
                        % Update biggestAWGRadius for the next "Strict Step"
                        if thisRadius > app.biggestAWGRadius
                            app.biggestAWGRadius = thisRadius;
                        end
                        
                        % Update the necklace state
                        app.circleList = [app.circleList, newWire];
                        app.circlePrev = newWire;
            
                        % Plot the new wire
                        viscircles(app.UIAxes, [newWire.x, newWire.y], newWire.r, 'EdgeColor', 'b');
                        plot(app.UIAxes, newWire.x, newWire.y, 'k+'); 

                        % This is the diameter of the path the wire's center follows
                        precisePCD = 2 * (app.distanceFromCenter + thisRadius);
                        % Calculate Lay Length using the precise PCD
                        layLength = app.LayFactor * precisePCD;
                        helixFactor = sqrt(1 + (pi / app.LayFactor)^2);
                        tLength = wireLen * helixFactor;
                        % Add to Table
                        wireNum = length(app.circleList); 
                        newRow = {app.layer, awg, insulationRad, wireLen, tLength, layLength, wireNum};
                        app.UITable.Data = [app.UITable.Data; newRow];
                    end
                end
        end

        % Value changed function: LayerEditField
        function LayerEditFieldValueChanged(app, event)
            value = app.LayerEditField.Value;
            
        end

        % Button pushed function: RemoveWireButton
        function RemoveWireButtonPushed(app, event)
            % Safety check: If no core, nothing to remove
            if ~app.coreBuilt
                return
            end

            % Remove the last row from the UI Table
            if ~isempty(app.UITable.Data)
                app.UITable.Data(end, :) = [];
            end

            % Determine if we are removing a Ring Wire or the Core
            % If circleList has more than 1 item, or if we are in Layer 1+, 
            % we are removing a ring wire.
            if length(app.circleList) > 1 || (app.layer > 0 && ~isempty(app.circleList))
        
                % Remove the last wire from the list
                app.circleList(end) = [];
        
                % Update the neighbor pointer (circlePrev) for the NEXT 'Add' click
                if isempty(app.circleList)
                    app.circlePrev = []; % Next wire will use buildFirst logic
                else
                    app.circlePrev = app.circleList(end);
                end
        
                % Recalculate biggestAWGRadius for the remaining wires in this layer
                if isempty(app.circleList)
                    app.biggestAWGRadius = 0;
                else
                    radii = [app.circleList.r];
                    app.biggestAWGRadius = max(radii);
                end

            elseif app.layer == 0 && length(app.circleList) == 1
                % REMOVING THE CORE
                app.circleList = [];
                app.coreBuilt = false;
                app.distanceFromCenter = 0;
                % Re-enable the Lay Factor field so it can be changed for a new build
                app.LayFactorEditField.Editable = 'on';
            end

            % 4. Redraw everything to keep the UI clean
            app.refreshPlot();
        end

        % Value changed function: LayFactorEditField
        function LayFactorEditFieldValueChanged(app, event)
            value = app.LayFactorEditField.Value;
            app.LayFactor = value;
        end

        % Button pushed function: NextLayerButton
        function NextLayerButtonPushed(app, event)
            % 1. Safety check: Don't jump if no wires were actually added to the current layer
            % This prevents the user from clicking 'Next Layer' twice and doubling the gap
            if isempty(app.circleList)
                return
            end

            % 2. The "Strict Step" Logic
            % If we are currently on Layer 1 (or higher), we push the boundary out 
            % by exactly the diameter (2*r) of the largest wire in the necklace.
            if app.layer > 0
                app.distanceFromCenter = app.distanceFromCenter + (2 * app.biggestAWGRadius);
            end

            % 3. Apply the new accumulation to the "Virtual Core"
            % This is the new floor that Layer + 1 wires will sit on.
            app.circleCore.r = app.distanceFromCenter;

            % 4. Increment the layer count for the UI
            app.layer = app.layer + 1;
            app.LayerEditField.Value = app.layer
            % Optional: Update a label in your app to show the user they are on a new layer
            % app.LayerLabel.Text = sprintf('Layer: %d', app.layer);

            % 5. CALL YOUR RESET FUNCTION
            % This clears the list, the neighbor pointer, and the biggestAWGRadius
            app.resetLPbA();

            % 6. Visual Feedback
            % Draw a faint dashed circle to show the new "Shelf" we are building on
            viscircles(app.UIAxes, [0, 0], app.circleCore.r, ...
                'Color', [0.8 0.8 0.8], 'LineStyle', '--');
    
            fprintf('Next Layer Logic: New Core Radius is %.4f inches.\n', app.distanceFromCenter);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wire Cross Section')
            xlabel(app.UIAxes, 'Cross Section Width (inch)')
            ylabel(app.UIAxes, 'Cross Section Height (inch)')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Layer'; 'AWG'; 'Insulation Rad(in)'; 'Length(in)'; 'Twisted Length(in)'; 'Lay Length(in)'; 'Wire #'};
            app.UITable.RowName = {};
            app.AddWireButton = uibutton(app.UIFigure, 'push');
            app.AddWireButton.ButtonPushedFcn = createCallbackFcn(app, @AddWireButtonPushed, true);
            app.AddWireButton.Text = 'Add Wire';
            app.LayerEditFieldLabel = uilabel(app.UIFigure);
            app.LayerEditFieldLabel.Text = 'Layer';
            app.LayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerEditField.ValueChangedFcn = createCallbackFcn(app, @LayerEditFieldValueChanged, true);
            app.LayerEditField.Editable = 'off';
            app.AWGEditFieldLabel = uilabel(app.UIFigure);
            app.AWGEditFieldLabel.Text = 'AWG';
            app.AWGEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthinEditFieldLabel = uilabel(app.UIFigure);
            app.LengthinEditFieldLabel.Text = 'Length(in)';
            app.LengthinEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemoveWireButton = uibutton(app.UIFigure, 'push');
            app.RemoveWireButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWireButtonPushed, true);
            app.RemoveWireButton.Text = 'Remove Wire';
            app.NextLayerButton = uibutton(app.UIFigure, 'push');
            app.NextLayerButton.ButtonPushedFcn = createCallbackFcn(app, @NextLayerButtonPushed, true);
            app.NextLayerButton.Text = 'Next Layer';
            app.LayFactorEditFieldLabel = uilabel(app.UIFigure);
            app.LayFactorEditFieldLabel.Text = 'LayFactor';
            app.LayFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayFactorEditField.ValueChangedFcn = createCallbackFcn(app, @LayFactorEditFieldValueChanged, true);
            app.LayFactorEditField.Value = 12;
            app.InsulationRadinEditFieldLabel = uilabel(app.UIFigure);
            app.InsulationRadinEditFieldLabel.Text = 'Insulation Rad(in)';
            app.InsulationRadinEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WireTwistViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end